classdef App < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                     matlab.ui.Figure
        TabGroup_3                   matlab.ui.container.TabGroup
        BlueTab_3                    matlab.ui.container.Tab
        UIAxesB_3                    matlab.ui.control.UIAxes
        GreenTab_3                   matlab.ui.container.Tab
        UIAxesG_3                    matlab.ui.control.UIAxes
        RedTab_3                     matlab.ui.container.Tab
        UIAxesR_3                    matlab.ui.control.UIAxes
        GrayTab_3                    matlab.ui.container.Tab
        UIAxesGray_3                 matlab.ui.control.UIAxes
        PowerTransformationCheckBox  matlab.ui.control.CheckBox
        LogTransformationCheckBox    matlab.ui.control.CheckBox
        ContrastStretchingCheckBox   matlab.ui.control.CheckBox
        NegativeCheckBox             matlab.ui.control.CheckBox
        BrighteningCheckBox          matlab.ui.control.CheckBox
        InputReferenceImageButton    matlab.ui.control.Button
        Image_3                      matlab.ui.control.Image
        TabGroup_2                   matlab.ui.container.TabGroup
        GrayTab_2                    matlab.ui.container.Tab
        UIAxesGray_2                 matlab.ui.control.UIAxes
        RedTab_2                     matlab.ui.container.Tab
        UIAxesR_2                    matlab.ui.control.UIAxes
        GreenTab_2                   matlab.ui.container.Tab
        UIAxesG_2                    matlab.ui.control.UIAxes
        BlueTab_2                    matlab.ui.container.Tab
        UIAxesB_2                    matlab.ui.control.UIAxes
        TabGroup                     matlab.ui.container.TabGroup
        GrayTab                      matlab.ui.container.Tab
        UIAxesGray                   matlab.ui.control.UIAxes
        RedTab                       matlab.ui.container.Tab
        UIAxesR                      matlab.ui.control.UIAxes
        GreenTab                     matlab.ui.container.Tab
        UIAxesG                      matlab.ui.control.UIAxes
        BlueTab                      matlab.ui.container.Tab
        UIAxesB                      matlab.ui.control.UIAxes
        ProcessButton                matlab.ui.control.Button
        EditField                    matlab.ui.control.NumericEditField
        EditFieldLabel               matlab.ui.control.Label
        cEditField_2                 matlab.ui.control.NumericEditField
        cEditField_2Label            matlab.ui.control.Label
        cEditField                   matlab.ui.control.NumericEditField
        cEditFieldLabel              matlab.ui.control.Label
        bEditField                   matlab.ui.control.NumericEditField
        bEditFieldLabel              matlab.ui.control.Label
        aEditField                   matlab.ui.control.NumericEditField
        aEditFieldLabel              matlab.ui.control.Label
        InputImageButton             matlab.ui.control.Button
        OperationDropDown            matlab.ui.control.DropDown
        OperationDropDownLabel       matlab.ui.control.Label
        Image_2                      matlab.ui.control.Image
        Image                        matlab.ui.control.Image
    end

    
    properties (Access = private)
    end
  

    % Callbacks that handle component events
    methods (Access = private)

        % Button pushed function: InputImageButton
        function InputImageButtonPushed(app, event)
            % Open file selection dialog for supported formats
            [file, path] = uigetfile({'*.tif;*.bmp;*.png;*.jpg;*.jpeg;*.gif;*.svg', ...
                                      'All Image Files (*.tif, *.bmp, *.png, *.jpg, *.jpeg, *.gif, *.svg)'; ...
                                      '*.*', 'All Files (*.*)'}, ...
                                     'Select an Image File');
            % Check if user selected a file or clicked cancel
            if isequal(file, 0)
                uialert(app.UIFigure, 'No file selected', 'File Selection');
                return;
            end
        
            % Full file path
            filepath = fullfile(path, file);
            
            % Reset all UIs
            app.Image.ImageSource = '';
            app.Image_2.ImageSource = '';
            app.Image_3.ImageSource = '';
            cla(app.UIAxesGray);
            cla(app.UIAxesGray_2);
            cla(app.UIAxesR);
            cla(app.UIAxesR_2);
            cla(app.UIAxesG);
            cla(app.UIAxesG_2);
            cla(app.UIAxesB);
            cla(app.UIAxesB_2);

            % Try to read and convert the image if it's a BMP file
            [~, ~, ext] = fileparts(filepath);
            if strcmpi(ext, '.bmp') || strcmpi(ext, '.tif')
                % Convert BMP to a temporary PNG file
                img = imread(filepath);
                
                % Use tempname to generate a complete file path
                tempfile = [tempname, '.png'];  % Correctly append '.png' to tempname
                
                % Write the image to the temporary file
                imwrite(img, tempfile);
                
                % Update the Image component's ImageSource property
                app.Image.ImageSource = tempfile;  % Use the complete file path
            else
                % Use the selected file directly if it's already a supported format
                app.Image.ImageSource = filepath;
            end
            
            if ndims(imread(filepath)) == 3
                displayHistogram(imread(app.Image.ImageSource), [app.UIAxesR, app.UIAxesG, app.UIAxesB]);
            else
                displayHistogram(imread(app.Image.ImageSource), app.UIAxesGray);
            end
            
        end

        % Value changed function: OperationDropDown
        function OperationDropDownValueChanged(app, event)
            value = app.OperationDropDown.Value;

            if isequal(value, "Image Enhancement")
                app.BrighteningCheckBox.Visible = "on";
                app.EditField.Visible = "on";
                app.NegativeCheckBox.Visible = "on";
                app.LogTransformationCheckBox.Visible = "on";
                app.PowerTransformationCheckBox.Visible = "on";
                app.ContrastStretchingCheckBox.Visible = "on";
                app.aEditField.Visible = "on";
                app.aEditFieldLabel.Visible = "on";
                app.bEditField.Visible = "on";
                app.bEditFieldLabel.Visible = "on";
                app.cEditField.Visible = "on";
                app.cEditFieldLabel.Visible = "on";
                app.cEditField_2.Visible = "on";
                app.cEditField_2Label.Visible = "on";
                app.Image_3.Visible = "off";
                app.InputReferenceImageButton.Visible = "off";
                app.TabGroup_3.Visible = "off";
            end

            if isequal(value, "Histogram Equalization")
                app.BrighteningCheckBox.Visible = "off";
                app.EditField.Visible = "off";
                app.NegativeCheckBox.Visible = "off";
                app.LogTransformationCheckBox.Visible = "off";
                app.PowerTransformationCheckBox.Visible = "off";
                app.ContrastStretchingCheckBox.Visible = "off";
                app.aEditField.Visible = "off";
                app.aEditFieldLabel.Visible = "off";
                app.bEditField.Visible = "off";
                app.bEditFieldLabel.Visible = "off";
                app.cEditField.Visible = "off";
                app.cEditFieldLabel.Visible = "off";
                app.cEditField_2.Visible = "off";
                app.cEditField_2Label.Visible = "off";
                app.Image_3.Visible = "off";
                app.InputReferenceImageButton.Visible = "off";
                app.TabGroup_3.Visible = "off";
            end

            if isequal(value, "Histogram Specification")
                app.BrighteningCheckBox.Visible = "off";
                app.EditField.Visible = "off";
                app.NegativeCheckBox.Visible = "off";
                app.LogTransformationCheckBox.Visible = "off";
                app.PowerTransformationCheckBox.Visible = "off";
                app.ContrastStretchingCheckBox.Visible = "off";
                app.aEditField.Visible = "off";
                app.aEditFieldLabel.Visible = "off";
                app.bEditField.Visible = "off";
                app.bEditFieldLabel.Visible = "off";
                app.cEditField.Visible = "off";
                app.cEditFieldLabel.Visible = "off";
                app.cEditField_2.Visible = "off";
                app.cEditField_2Label.Visible = "off";
                app.Image_3.Visible = "on";
                app.InputReferenceImageButton.Visible = "on";
                app.TabGroup_3.Visible = "on";
            end
        end

        % Button pushed function: ProcessButton
        function ProcessButtonPushed(app, event)
            if isequal(app.Image.ImageSource, '')
                uialert(app.UIFigure, 'No input file detected', 'No Input');
                return;
            end
            
            originalImg = imread(app.Image.ImageSource);

            enhancedImg = originalImg;
            
            if strcmp(app.OperationDropDown.Value, "Image Enhancement") == 1
                if app.BrighteningCheckBox.Value
                    enhancedImg = brightening(originalImg, app.aEditField.Value, app.bEditField.Value);
                end
                
                if app.NegativeCheckBox.Value
                    enhancedImg = negative(enhancedImg);
                end
                
                if app.LogTransformationCheckBox.Value
                    enhancedImg = logTransformation(enhancedImg, app.cEditField.Value);
                end
    
                if app.PowerTransformationCheckBox.Value
                    enhancedImg = powerTransformation(enhancedImg, app.cEditField_2.Value, app.EditField.Value);
                end
    
                if app.ContrastStretchingCheckBox.Value
                    enhancedImg = contrastStretching(enhancedImg);
                end
            end

            if strcmp(app.OperationDropDown.Value, "Histogram Equalization") == 1
                enhancedImg = equalizeHistogram(enhancedImg);
            end

            if strcmp(app.OperationDropDown.Value, "Histogram Specification") == 1
                if isequal(app.Image_3.ImageSource, '')
                    uialert(app.UIFigure, 'No reference file detected', 'No Input');
                    return;
                end
                % TODO: ref image have to be the same color channel with
                % input image
                enhancedImg = specificateHistogram(enhancedImg, imread(app.Image_3.ImageSource));
            end
            
            if ndims(enhancedImg) == 3
                displayHistogram(enhancedImg, [app.UIAxesR_2, app.UIAxesG_2, app.UIAxesB_2]);
            else
                displayHistogram(enhancedImg, app.UIAxesGray_2);
                enhancedImg = cat(3, enhancedImg, enhancedImg, enhancedImg);
            end

            app.Image_2.ImageSource = enhancedImg;
        end

        % Button pushed function: InputReferenceImageButton
        function InputReferenceImageButtonPushed(app, event)
            if isequal(app.Image.ImageSource, '')
                uialert(app.UIFigure, 'Please insert input image first', 'No Input');
                return;
            end
            
            % Open file selection dialog for supported formats
            [file, path] = uigetfile({'*.tif;*.bmp;*.png;*.jpg;*.jpeg;*.gif;*.svg', ...
                                      'All Image Files (*.tif, *.bmp, *.png, *.jpg, *.jpeg, *.gif, *.svg)'; ...
                                      '*.*', 'All Files (*.*)'}, ...
                                     'Select an Image File');
            % Check if user selected a file or clicked cancel
            if isequal(file, 0)
                uialert(app.UIFigure, 'No file selected', 'File Selection');
                return;
            end
        
            % Full file path
            filepath = fullfile(path, file);
            
            % Check if input image and reference image have same color
            % channel and dimension
            if ~isequal(ndims(imread(filepath)), ndims(imread(app.Image.ImageSource)))
                uialert(app.UIFigure, "Reference image must have the same dimension and color channels as input image", 'Input Error');
                return;
            end

            % Reset all UIs
            app.Image_3.ImageSource = '';
            cla(app.UIAxesGray_3);
            cla(app.UIAxesR_3);
            cla(app.UIAxesG_3);
            cla(app.UIAxesB_3);

            % Try to read and convert the image if it's a BMP file
            [~, ~, ext] = fileparts(filepath);
            if strcmpi(ext, '.bmp') || strcmpi(ext, '.tif')
                % Convert BMP to a temporary PNG file
                img = imread(filepath);
                tempPath = fullfile(tempdir, 'ref_image.png');
                imwrite(img, tempPath);
                
                % Update the Image component's ImageSource property
                app.Image_3.ImageSource = tempPath;
            else
                % Use the selected file directly if it's already a supported format
                app.Image_3.ImageSource = filepath;
            end
            
            if ndims(imread(filepath)) == 3
                displayHistogram(imread(app.Image_3.ImageSource), [app.UIAxesR_3, app.UIAxesG_3, app.UIAxesB_3]);
            else
                displayHistogram(imread(app.Image_3.ImageSource), app.UIAxesGray_3);
            end
            
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.Image = uiimage(app.UIFigure);
            app.Image_2 = uiimage(app.UIFigure);
            app.OperationDropDownLabel = uilabel(app.UIFigure);
            app.OperationDropDownLabel.Text = 'Operation';
            app.OperationDropDown = uidropdown(app.UIFigure);
            app.OperationDropDown.Items = {'Image Enhancement', 'Histogram Equalization', 'Histogram Specification'};
            app.OperationDropDown.ValueChangedFcn = createCallbackFcn(app, @OperationDropDownValueChanged, true);
            app.OperationDropDown.Value = 'Image Enhancement';
            app.InputImageButton = uibutton(app.UIFigure, 'push');
            app.InputImageButton.ButtonPushedFcn = createCallbackFcn(app, @InputImageButtonPushed, true);
            app.InputImageButton.Text = 'Input Image';
            app.aEditFieldLabel = uilabel(app.UIFigure);
            app.aEditFieldLabel.Text = 'a';
            app.aEditField = uieditfield(app.UIFigure, 'numeric');
            app.aEditField.Value = 1;
            app.bEditFieldLabel = uilabel(app.UIFigure);
            app.bEditFieldLabel.Text = 'b';
            app.bEditField = uieditfield(app.UIFigure, 'numeric');
            app.cEditFieldLabel = uilabel(app.UIFigure);
            app.cEditFieldLabel.Text = 'c';
            app.cEditField = uieditfield(app.UIFigure, 'numeric');
            app.cEditField_2Label = uilabel(app.UIFigure);
            app.cEditField_2Label.Text = 'c';
            app.cEditField_2 = uieditfield(app.UIFigure, 'numeric');
            app.cEditField_2.Value = 1;
            app.EditFieldLabel = uilabel(app.UIFigure);
            app.EditFieldLabel.Text = '𝛾';
            app.EditField = uieditfield(app.UIFigure, 'numeric');
            app.EditField.Value = 1;
            app.ProcessButton = uibutton(app.UIFigure, 'push');
            app.ProcessButton.ButtonPushedFcn = createCallbackFcn(app, @ProcessButtonPushed, true);
            app.ProcessButton.Text = 'Process';
            app.TabGroup = uitabgroup(app.UIFigure);
            app.GrayTab = uitab(app.TabGroup);
            app.GrayTab.Title = 'Gray';
            app.UIAxesGray = uiaxes(app.GrayTab);
            title(app.UIAxesGray, 'Title')
            xlabel(app.UIAxesGray, 'X')
            ylabel(app.UIAxesGray, 'Y')
            zlabel(app.UIAxesGray, 'Z')
            app.RedTab = uitab(app.TabGroup);
            app.RedTab.Title = 'Red';
            app.UIAxesR = uiaxes(app.RedTab);
            title(app.UIAxesR, 'Title')
            xlabel(app.UIAxesR, 'X')
            ylabel(app.UIAxesR, 'Y')
            zlabel(app.UIAxesR, 'Z')
            app.GreenTab = uitab(app.TabGroup);
            app.GreenTab.Title = 'Green';
            app.UIAxesG = uiaxes(app.GreenTab);
            title(app.UIAxesG, 'Title')
            xlabel(app.UIAxesG, 'X')
            ylabel(app.UIAxesG, 'Y')
            zlabel(app.UIAxesG, 'Z')
            app.BlueTab = uitab(app.TabGroup);
            app.BlueTab.Title = 'Blue';
            app.UIAxesB = uiaxes(app.BlueTab);
            title(app.UIAxesB, 'Title')
            xlabel(app.UIAxesB, 'X')
            ylabel(app.UIAxesB, 'Y')
            zlabel(app.UIAxesB, 'Z')
            app.TabGroup_2 = uitabgroup(app.UIFigure);
            app.GrayTab_2 = uitab(app.TabGroup_2);
            app.GrayTab_2.Title = 'Gray';
            app.UIAxesGray_2 = uiaxes(app.GrayTab_2);
            title(app.UIAxesGray_2, 'Title')
            xlabel(app.UIAxesGray_2, 'X')
            ylabel(app.UIAxesGray_2, 'Y')
            zlabel(app.UIAxesGray_2, 'Z')
            app.RedTab_2 = uitab(app.TabGroup_2);
            app.RedTab_2.Title = 'Red';
            app.UIAxesR_2 = uiaxes(app.RedTab_2);
            title(app.UIAxesR_2, 'Title')
            xlabel(app.UIAxesR_2, 'X')
            ylabel(app.UIAxesR_2, 'Y')
            zlabel(app.UIAxesR_2, 'Z')
            app.GreenTab_2 = uitab(app.TabGroup_2);
            app.GreenTab_2.Title = 'Green';
            app.UIAxesG_2 = uiaxes(app.GreenTab_2);
            title(app.UIAxesG_2, 'Title')
            xlabel(app.UIAxesG_2, 'X')
            ylabel(app.UIAxesG_2, 'Y')
            zlabel(app.UIAxesG_2, 'Z')
            app.BlueTab_2 = uitab(app.TabGroup_2);
            app.BlueTab_2.Title = 'Blue';
            app.UIAxesB_2 = uiaxes(app.BlueTab_2);
            title(app.UIAxesB_2, 'Title')
            xlabel(app.UIAxesB_2, 'X')
            ylabel(app.UIAxesB_2, 'Y')
            zlabel(app.UIAxesB_2, 'Z')
            app.Image_3 = uiimage(app.UIFigure);
            app.Image_3.Visible = 'off';
            app.InputReferenceImageButton = uibutton(app.UIFigure, 'push');
            app.InputReferenceImageButton.ButtonPushedFcn = createCallbackFcn(app, @InputReferenceImageButtonPushed, true);
            app.InputReferenceImageButton.Visible = 'off';
            app.InputReferenceImageButton.Text = 'Input Reference Image';
            app.BrighteningCheckBox = uicheckbox(app.UIFigure);
            app.BrighteningCheckBox.Text = 'Brightening';
            app.NegativeCheckBox = uicheckbox(app.UIFigure);
            app.NegativeCheckBox.Text = 'Negative';
            app.ContrastStretchingCheckBox = uicheckbox(app.UIFigure);
            app.ContrastStretchingCheckBox.Text = 'Contrast Stretching';
            app.LogTransformationCheckBox = uicheckbox(app.UIFigure);
            app.LogTransformationCheckBox.Text = 'Log Transformation';
            app.PowerTransformationCheckBox = uicheckbox(app.UIFigure);
            app.PowerTransformationCheckBox.Text = 'Power Transformation';
            app.TabGroup_3 = uitabgroup(app.UIFigure);
            app.TabGroup_3.Visible = 'off';
            app.GrayTab_3 = uitab(app.TabGroup_3);
            app.GrayTab_3.Title = 'Gray';
            app.UIAxesGray_3 = uiaxes(app.GrayTab_3);
            title(app.UIAxesGray_3, 'Title')
            xlabel(app.UIAxesGray_3, 'X')
            ylabel(app.UIAxesGray_3, 'Y')
            zlabel(app.UIAxesGray_3, 'Z')
            app.RedTab_3 = uitab(app.TabGroup_3);
            app.RedTab_3.Title = 'Red';
            app.UIAxesR_3 = uiaxes(app.RedTab_3);
            title(app.UIAxesR_3, 'Title')
            xlabel(app.UIAxesR_3, 'X')
            ylabel(app.UIAxesR_3, 'Y')
            zlabel(app.UIAxesR_3, 'Z')
            app.GreenTab_3 = uitab(app.TabGroup_3);
            app.GreenTab_3.Title = 'Green';
            app.UIAxesG_3 = uiaxes(app.GreenTab_3);
            title(app.UIAxesG_3, 'Title')
            xlabel(app.UIAxesG_3, 'X')
            ylabel(app.UIAxesG_3, 'Y')
            zlabel(app.UIAxesG_3, 'Z')
            app.BlueTab_3 = uitab(app.TabGroup_3);
            app.BlueTab_3.Title = 'Blue';
            app.UIAxesB_3 = uiaxes(app.BlueTab_3);
            title(app.UIAxesB_3, 'Title')
            xlabel(app.UIAxesB_3, 'X')
            ylabel(app.UIAxesB_3, 'Y')
            zlabel(app.UIAxesB_3, 'Z')
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = App

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end